classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CrieseupropriomodeloTab         matlab.ui.container.Tab
        EditField                       matlab.ui.control.EditField
        Lamp_7                          matlab.ui.control.Lamp
        Lamp_6                          matlab.ui.control.Lamp
        Lamp_5                          matlab.ui.control.Lamp
        Lamp_4                          matlab.ui.control.Lamp
        Lamp_3                          matlab.ui.control.Lamp
        Lamp_2                          matlab.ui.control.Lamp
        Lamp                            matlab.ui.control.Lamp
        TreineseumodeloButton           matlab.ui.control.Button
        Dataset500numerosButton         matlab.ui.control.Button
        Dataset1000numerosButton        matlab.ui.control.Button
        GoalEditField                   matlab.ui.control.NumericEditField
        GoalEditFieldLabel              matlab.ui.control.Label
        TrainingfunctionEditField       matlab.ui.control.EditField
        TrainingfunctionEditFieldLabel  matlab.ui.control.Label
        EphocsEditField                 matlab.ui.control.NumericEditField
        EphocsEditFieldLabel            matlab.ui.control.Label
        TransferfunctionEditField       matlab.ui.control.EditField
        TransferfunctionEditFieldLabel  matlab.ui.control.Label
        Dataset1700numerosButton        matlab.ui.control.Button
        ModelospredefinidosTab          matlab.ui.container.Tab
        TwoLayersButton                 matlab.ui.control.Button
        OneLayerButton                  matlab.ui.control.Button
        EditField_7                     matlab.ui.control.EditField
        EditField_6                     matlab.ui.control.EditField
        BinaryButton                    matlab.ui.control.Button
        SigmoidalButton                 matlab.ui.control.Button
        LinearButton                    matlab.ui.control.Button
        EditField_4                     matlab.ui.control.EditField
        EditField_2                     matlab.ui.control.EditField
        GerenciamentodefiltrosTab       matlab.ui.container.Tab
        Switch3                         matlab.ui.control.Switch
        Switch2                         matlab.ui.control.Switch
        Switch                          matlab.ui.control.Switch
        AssociativememoryButton         matlab.ui.control.Button
        BinaryperceptronfilterButton    matlab.ui.control.Button
        SemfiltroButton                 matlab.ui.control.Button
        TesteseumodeloTab               matlab.ui.container.Tab
        UsandoEditField                 matlab.ui.control.EditField
        UsandoEditFieldLabel            matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Dataset1700numerosButton
        function Dataset1700numerosButtonPushed(app, event)
            app.EditField.Value="gigante.mat";
            app.Lamp.Color='green';
            app.Lamp_2.Color='red';
            app.Lamp_3.Color='red';
        end

        % Button pushed function: TreineseumodeloButton
        function TreineseumodeloButtonPushed(app, event)
            if(app.EditField.Value=="gigante.mat")
                P=load(app.EditField.Value,"gigante");
                P=P.gigante;
            else
                P=load(app.EditField.Value,"final");
                P=P.final;
            end
            treino_A(P,app.TransferfunctionEditField.Value,app.TrainingfunctionEditField.Value,app.EphocsEditField.Value,app.GoalEditField.Value);
            app.UsandoEditField.Value="Modelo proprio";
        end

        % Button pushed function: Dataset500numerosButton
        function Dataset500numerosButtonPushed(app, event)
            app.EditField.Value="final.mat";
            app.Lamp.Color='red';
            app.Lamp_2.Color='red';
            app.Lamp_3.Color='green';
        end

        % Value changed function: TransferfunctionEditField
        function TransferfunctionEditFieldValueChanged(app, event)
            transferFunc = app.TransferfunctionEditField.Value;          
        end

        % Value changed function: TrainingfunctionEditField
        function TrainingfunctionEditFieldValueChanged(app, event)
            trainingFunc = app.TrainingfunctionEditField.Value;
        end

        % Value changed function: EphocsEditField
        function EphocsEditFieldValueChanged(app, event)
            ephocs = app.EphocsEditField.Value;
            if(ephocs~=0)
                app.Lamp_6.Color='green';
            else
                app.Lamp_6.Color='red';
            end
        end

        % Value changed function: GoalEditField
        function GoalEditFieldValueChanged(app, event)
            goal = app.GoalEditField.Value;
            if(goal~=0)
                app.Lamp_7.Color='green';
            else
                app.Lamp_7.Color='red';
            end
        end

        % Context menu opening function: ContextMenu
        function ContextMenuOpening(app, event)
            
        end

        % Value changing function: TransferfunctionEditField
        function TransferfunctionEditFieldValueChanging(app, event)
            changingValue = event.Value;
            if(changingValue)
                app.Lamp_4.Color='green';
            end
        end

        % Value changing function: TrainingfunctionEditField
        function TrainingfunctionEditFieldValueChanging(app, event)
            changingValue = event.Value;
            if(changingValue)
                app.Lamp_5.Color='green';
            end
        end

        % Button pushed function: Dataset1000numerosButton
        function Dataset1000numerosButtonPushed(app, event)
            app.EditField.Value="gigante.mat";
            app.Lamp.Color='red';
            app.Lamp_2.Color='green';
            app.Lamp_3.Color='red';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if(app.UsandoEditField.Value=="Modelo pre-definido")
                a= load(strcat("models/",app.EditField_6.Value,app.EditField_7.Value),"net");
                net=a.net;
                save("models/TESTE.mat","net");
            end
            mpaper;
        end

        % Callback function: not associated with a component
        function GradientdescentwithadaptiveleaningrateButtonPushed(app, event)
            app.EditField_7.Value="_traingda";
            app.EditField_2.Value=strcat("Currently using: ",app.EditField_6.Value,app.EditField_7.Value,".mat");
            app.UsandoEditField.Value="Modelo pre-definido";
        end

        % Button pushed function: LinearButton
        function LinearButtonPushed(app, event)
            app.EditField_6.Value="netLinear";
            app.EditField_2.Value=strcat("Currently using: ",app.EditField_6.Value,app.EditField_7.Value,".mat");
            app.UsandoEditField.Value="Modelo pre-definido";
        end

        % Value changing function: EditField_2
        function EditField_2ValueChanging(app, event)

        end

        % Callback function: not associated with a component
        function ScaledconjugategradientButtonPushed(app, event)

        end

        % Callback function: not associated with a component
        function GradientdescentButtonPushed(app, event)

        end

        % Callback function: not associated with a component
        function GradientwithmomentButtonPushed(app, event)

        end

        % Callback function: not associated with a component
        function LevenbergMarquardtButtonPushed(app, event)

        end

        % Button pushed function: SigmoidalButton
        function SigmoidalButtonPushed(app, event)
            app.EditField_6.Value="netSigmoid";
            app.EditField_2.Value=strcat("Currently using: ",app.EditField_6.Value,app.EditField_7.Value,".mat");
            app.UsandoEditField.Value="Modelo pre-definido";
        end

        % Button pushed function: BinaryButton
        function BinaryButtonPushed(app, event)
            app.EditField_6.Value="netHardim";
            app.EditField_2.Value=strcat("Currently using: ",app.EditField_6.Value,app.EditField_7.Value,".mat");
            app.UsandoEditField.Value="Modelo pre-definido";
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            noFilter = app.Switch.Value;
            app.Switch2.Value='Off';
            app.Switch3.Value='Off';
            perceptron=app.Switch2.Value;
            associativeMemory=app.Switch3.Value;
            save("perceptron.mat","perceptron");
            save("noFilter.mat","noFilter");
            save("associativeMemory","associativeMemory");
        end

        % Value changed function: Switch2
        function Switch2ValueChanged(app, event)
            perceptron = app.Switch2.Value;
            app.Switch.Value='Off';
            app.Switch3.Value='Off';
            noFilter=app.Switch.Value;
            associativeMemory=app.Switch3.Value;
            save("perceptron.mat","perceptron");
            save("noFilter.mat","noFilter");
            save("associativeMemory","associativeMemory");
        end

        % Value changed function: Switch3
        function Switch3ValueChanged(app, event)
            associativeMemory = app.Switch3.Value;
            app.Switch2.Value='Off';
            app.Switch.Value='Off';
            noFilter=app.Switch.Value;
            perceptron=app.Switch2.Value;
            save("perceptron.mat","perceptron");
            save("noFilter.mat","noFilter");
            save("associativeMemory","associativeMemory");
        end

        % Button pushed function: OneLayerButton
        function OneLayerButtonPushed(app, event)
            app.EditField_7.Value="_1L";
            app.EditField_2.Value=strcat("Currently using: ",app.EditField_6.Value,app.EditField_7.Value,".mat");
            app.UsandoEditField.Value="Modelo pre-definido";
        end

        % Button pushed function: TwoLayersButton
        function TwoLayersButtonPushed(app, event)
            app.EditField_7.Value="_2L";
            app.EditField_2.Value=strcat("Currently using: ",app.EditField_6.Value,app.EditField_7.Value,".mat");
            app.UsandoEditField.Value="Modelo pre-definido";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CrieseupropriomodeloTab = uitab(app.TabGroup);
            app.CrieseupropriomodeloTab.Title = 'Crie seu proprio modelo';
            app.Dataset1700numerosButton = uibutton(app.CrieseupropriomodeloTab, 'push');
            app.Dataset1700numerosButton.ButtonPushedFcn = createCallbackFcn(app, @Dataset1700numerosButtonPushed, true);
            app.Dataset1700numerosButton.Text = 'Data set 1700 numeros';
            app.TransferfunctionEditFieldLabel = uilabel(app.CrieseupropriomodeloTab);
            app.TransferfunctionEditFieldLabel.Text = 'Transfer function';
            app.TransferfunctionEditField = uieditfield(app.CrieseupropriomodeloTab, 'text');
            app.TransferfunctionEditField.ValueChangedFcn = createCallbackFcn(app, @TransferfunctionEditFieldValueChanged, true);
            app.TransferfunctionEditField.ValueChangingFcn = createCallbackFcn(app, @TransferfunctionEditFieldValueChanging, true);
            app.EphocsEditFieldLabel = uilabel(app.CrieseupropriomodeloTab);
            app.EphocsEditFieldLabel.Text = 'Ephocs';
            app.EphocsEditField = uieditfield(app.CrieseupropriomodeloTab, 'numeric');
            app.EphocsEditField.ValueChangedFcn = createCallbackFcn(app, @EphocsEditFieldValueChanged, true);
            app.TrainingfunctionEditFieldLabel = uilabel(app.CrieseupropriomodeloTab);
            app.TrainingfunctionEditFieldLabel.Text = 'Training function';
            app.TrainingfunctionEditField = uieditfield(app.CrieseupropriomodeloTab, 'text');
            app.TrainingfunctionEditField.ValueChangedFcn = createCallbackFcn(app, @TrainingfunctionEditFieldValueChanged, true);
            app.TrainingfunctionEditField.ValueChangingFcn = createCallbackFcn(app, @TrainingfunctionEditFieldValueChanging, true);
            app.GoalEditFieldLabel = uilabel(app.CrieseupropriomodeloTab);
            app.GoalEditFieldLabel.Text = 'Goal';
            app.GoalEditField = uieditfield(app.CrieseupropriomodeloTab, 'numeric');
            app.GoalEditField.ValueChangedFcn = createCallbackFcn(app, @GoalEditFieldValueChanged, true);
            app.Dataset1000numerosButton = uibutton(app.CrieseupropriomodeloTab, 'push');
            app.Dataset1000numerosButton.ButtonPushedFcn = createCallbackFcn(app, @Dataset1000numerosButtonPushed, true);
            app.Dataset1000numerosButton.Text = 'Data set 1000 numeros';
            app.Dataset500numerosButton = uibutton(app.CrieseupropriomodeloTab, 'push');
            app.Dataset500numerosButton.ButtonPushedFcn = createCallbackFcn(app, @Dataset500numerosButtonPushed, true);
            app.Dataset500numerosButton.Text = 'Data set 500 numeros';
            app.TreineseumodeloButton = uibutton(app.CrieseupropriomodeloTab, 'push');
            app.TreineseumodeloButton.ButtonPushedFcn = createCallbackFcn(app, @TreineseumodeloButtonPushed, true);
            app.TreineseumodeloButton.Text = 'Treine seu modelo!';
            app.Lamp = uilamp(app.CrieseupropriomodeloTab);
            app.Lamp_2 = uilamp(app.CrieseupropriomodeloTab);
            app.Lamp_3 = uilamp(app.CrieseupropriomodeloTab);
            app.Lamp_4 = uilamp(app.CrieseupropriomodeloTab);
            app.Lamp_5 = uilamp(app.CrieseupropriomodeloTab);
            app.Lamp_6 = uilamp(app.CrieseupropriomodeloTab);
            app.Lamp_7 = uilamp(app.CrieseupropriomodeloTab);
            app.EditField = uieditfield(app.CrieseupropriomodeloTab, 'text');
            app.EditField.Editable = 'off';
            app.ModelospredefinidosTab = uitab(app.TabGroup);
            app.ModelospredefinidosTab.Title = 'Modelos pre-definidos';
            app.EditField_2 = uieditfield(app.ModelospredefinidosTab, 'text');
            app.EditField_2.ValueChangingFcn = createCallbackFcn(app, @EditField_2ValueChanging, true);
            app.EditField_2.Editable = 'off';
            app.EditField_2.Value = 'Currently in use:';
            app.EditField_4 = uieditfield(app.ModelospredefinidosTab, 'text');
            app.EditField_4.Editable = 'off';
            app.EditField_4.Value = 'Activiation Functions';
            app.LinearButton = uibutton(app.ModelospredefinidosTab, 'push');
            app.LinearButton.ButtonPushedFcn = createCallbackFcn(app, @LinearButtonPushed, true);
            app.LinearButton.Text = 'Linear';
            app.SigmoidalButton = uibutton(app.ModelospredefinidosTab, 'push');
            app.SigmoidalButton.ButtonPushedFcn = createCallbackFcn(app, @SigmoidalButtonPushed, true);
            app.SigmoidalButton.Text = 'Sigmoidal';
            app.BinaryButton = uibutton(app.ModelospredefinidosTab, 'push');
            app.BinaryButton.ButtonPushedFcn = createCallbackFcn(app, @BinaryButtonPushed, true);
            app.BinaryButton.Text = 'Binary';
            app.EditField_6 = uieditfield(app.ModelospredefinidosTab, 'text');
            app.EditField_6.Editable = 'off';
            app.EditField_7 = uieditfield(app.ModelospredefinidosTab, 'text');
            app.EditField_7.Editable = 'off';
            app.OneLayerButton = uibutton(app.ModelospredefinidosTab, 'push');
            app.OneLayerButton.ButtonPushedFcn = createCallbackFcn(app, @OneLayerButtonPushed, true);
            app.OneLayerButton.Text = 'One Layer';
            app.TwoLayersButton = uibutton(app.ModelospredefinidosTab, 'push');
            app.TwoLayersButton.ButtonPushedFcn = createCallbackFcn(app, @TwoLayersButtonPushed, true);
            app.TwoLayersButton.Text = 'Two Layers';
            app.GerenciamentodefiltrosTab = uitab(app.TabGroup);
            app.GerenciamentodefiltrosTab.Title = 'Gerenciamento de filtros';
            app.SemfiltroButton = uibutton(app.GerenciamentodefiltrosTab, 'push');
            app.SemfiltroButton.Text = 'Sem filtro';
            app.BinaryperceptronfilterButton = uibutton(app.GerenciamentodefiltrosTab, 'push');
            app.BinaryperceptronfilterButton.Text = 'Binary perceptron filter';
            app.AssociativememoryButton = uibutton(app.GerenciamentodefiltrosTab, 'push');
            app.AssociativememoryButton.Text = 'Associative memory';
            app.Switch = uiswitch(app.GerenciamentodefiltrosTab, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'On';
            app.Switch2 = uiswitch(app.GerenciamentodefiltrosTab, 'slider');
            app.Switch2.ValueChangedFcn = createCallbackFcn(app, @Switch2ValueChanged, true);
            app.Switch3 = uiswitch(app.GerenciamentodefiltrosTab, 'slider');
            app.Switch3.ValueChangedFcn = createCallbackFcn(app, @Switch3ValueChanged, true);
            app.TesteseumodeloTab = uitab(app.TabGroup);
            app.TesteseumodeloTab.Title = 'Teste seu modelo';
            app.Button = uibutton(app.TesteseumodeloTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.UsandoEditFieldLabel = uilabel(app.TesteseumodeloTab);
            app.UsandoEditFieldLabel.Text = 'Usando: ';
            app.UsandoEditField = uieditfield(app.TesteseumodeloTab, 'text');
            app.UsandoEditField.Editable = 'off';
            app.UsandoEditField.Value = 'Modelo pre-definido';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenuOpening, true);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.Lamp.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end